classdef gui_algorithm_ozan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureOzan                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainScreenTab                   matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        CreatedbyOrhanOzanYldzLabel     matlab.ui.control.Label
        SortingTab                      matlab.ui.container.Tab
        UpperBoundsEditField            matlab.ui.control.NumericEditField
        UpperBoundsEditFieldLabel       matlab.ui.control.Label
        LowerBoundsEditField            matlab.ui.control.NumericEditField
        LowerBoundsEditFieldLabel       matlab.ui.control.Label
        ArraySizeEditField              matlab.ui.control.NumericEditField
        ArraySizeEditFieldLabel         matlab.ui.control.Label
        DifferentNumbersSwitchLabel     matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        TIMECOMPARISONButton            matlab.ui.control.Button
        BUCKETSORTButton                matlab.ui.control.Button
        COUNTINGSORTButton              matlab.ui.control.Button
        RADIXSORTButton                 matlab.ui.control.Button
        HEAPSORTButton                  matlab.ui.control.Button
        QUICKSORTButton                 matlab.ui.control.Button
        MERGESORTButton                 matlab.ui.control.Button
        BUBBLESORTButton                matlab.ui.control.Button
        INSERTIONSORTButton             matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        SKIPButton                      matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        SpeedSlider                     matlab.ui.control.Slider
        SpeedSliderLabel                matlab.ui.control.Label
        RANDOMARRAYButton               matlab.ui.control.Button
        Createmanuelarrayie2416Label    matlab.ui.control.Label
        CREATEARRAYButton               matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SeedCheckBox                    matlab.ui.control.CheckBox
        CreatearandomarrayLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ComparisonTab                   matlab.ui.container.Tab
        COMPAREALLMETHODSButton         matlab.ui.control.Button
        NumberOfElementsEditField       matlab.ui.control.NumericEditField
        NumberOfElementsEditFieldLabel  matlab.ui.control.Label
        UpperBoundEditField_2           matlab.ui.control.NumericEditField
        UpperBoundEditField_2Label      matlab.ui.control.Label
        IterationEditField_2            matlab.ui.control.NumericEditField
        IterationEditField_2Label       matlab.ui.control.Label
        CLEARGRAPHButton                matlab.ui.control.Button
        COMPARESELECTEDMETHODSButton    matlab.ui.control.Button
        COUNTINGSORTCheckBox            matlab.ui.control.CheckBox
        RADIXSORTCheckBox               matlab.ui.control.CheckBox
        QUICKSORTCheckBox               matlab.ui.control.CheckBox
        BUCKETSORTCheckBox              matlab.ui.control.CheckBox
        HEAPSORTCheckBox                matlab.ui.control.CheckBox
        MERGESORTCheckBox               matlab.ui.control.CheckBox
        BUBBLESORTCheckBox              matlab.ui.control.CheckBox
        INSERTIONSORTCheckBox_2         matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        MatrixMultiplicaitonTab         matlab.ui.container.Tab
        FibonacciSequanceTab            matlab.ui.container.Tab
        BinarySearchTab                 matlab.ui.container.Tab
        RandomizedSelectionTab          matlab.ui.container.Tab
    end


    properties (Access = private)
        changingValue_arraysize;
        changingValue_arrayrange;
        unsorted_array;
        changingValueRange;
        changingValueSize;
        changingValue
        CallingApp
        BUTON
    end

    methods (Access = private)
        %% This function PERFORMS the INSERTION sort algorithm.
        function array = insertion_sorting(app, array)
            x = (1:length(array));
            for j = 2:length(array)
                key = array(j);
                i = j - 1;
                % Find the correct position. Compare the smaller and greater of the numbers.
                while i > 0 && array(i) > key
                    % Do this only if key is smaller than other values, shift the value one position to the left.
                    array(i+1) = array(i);
                    i = i - 1;                                       % Reposition from right to left.
                    bar(app.UIAxes2,x,array,"black");                % A bar chart is created.
                    hold(app.UIAxes2,"on")                           % Retain current plot when adding new plots.
                    bar(app.UIAxes2,x(j),array(j),"blue")
                    bar(app.UIAxes2,x(i+1),array(i+1),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SpeedSlider.Value))*0.01);   % Speed slider to adjust bar animation speed.
                end
                array(i+1) = key;
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Insertion Sorting');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom','LineWidth',2)
            end
        end
        %% The END of the INSERTION sort algorithm.

        %% This function PERFORMS the BUBBLE sort algorithm.
        function array = bubble_sorting(app,array)
            x = (1:length(array));
            array_length = size(array,2);
            % Traverse through all array elements.
            for i = 1:(array_length + 1)
                % Last i elements are already in place.
                for j = array_length:-1:(i + 1)
                    % Traverse the array from 0 to array_length-i-1.
                    % Swap if the element found is greater
                    % than the next element.
                    if array(j) < array(j - 1)
                        result = array(j);
                        array(j) = array(j - 1);
                        array(j - 1) = result;
                        % A bar chart is created.
                        bar(app.UIAxes2,x,array,"black");
                        hold(app.UIAxes2,"on")
                        bar(app.UIAxes2,x(j),array(j),"magenta")
                        bar(app.UIAxes2,x(j-1),array(j-1),"cyan")
                        hold(app.UIAxes2,"off")
                        % Speed slider to adjust bar animation speed.
                        pause((10-floor(app.SpeedSlider.Value))*0.01);
                    end
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bubble Sorting');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
        end
        %% The END of the BUBBLE sort algorithm.

        %% This function PERFORMS the MERGE sort algorithm.
        function unsorted_array = Merge_Sort(app,unsorted_array,l,r)
            if(l < r)
                % Finding the mid of the array.
                mid = floor((l+r)/2);
                % Sorting the first half.
                unsorted_array = Merge_Sort(app,unsorted_array,l,mid);
                % Sorting the second half
                unsorted_array = Merge_Sort(app,unsorted_array,mid+1,r);
                unsorted_array = Merge(app,unsorted_array,l,mid,r);
            end
        end

        function unsorted_array = Merge(app,unsorted_array, l, mid, r)
            x = (1:length(unsorted_array));
            n1 = mid - l + 1;                   % First half of array
            n2 = r - mid;                       % Second half of array.
            Left = zeros(1, n1+1);              % Left side of list.
            Right = zeros(1, n2+1);             % Right side of list.
            % Copy remaining elements of left and right if any.
            for i = 1:n1
                Left(i) = unsorted_array(l + i - 1);
            end
            for j = 1:n2
                Right(j) = unsorted_array(mid + j);
            end
            Left(n1 + 1) = inf;
            Right(n2 + 1) = inf;
            i = 1;
            j = 1;

            for k=l:r
                if Left(i) <= Right(j)
                    unsorted_array(k) = Left(i);
                    i = i + 1;
                else
                    unsorted_array(k) = Right(j);
                    j = j + 1;
                end

                bar(app.UIAxes2,x,unsorted_array,"black");
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(k),unsorted_array(k),"magenta");
                hold(app.UIAxes2,"off");
                % Speed slider to adjust bar animation speed. % Speed slider to adjust bar animation speed.
                pause((10-floor(app.SpeedSlider.Value))*0.01);
            end

            bar(app.UIAxes2,unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Merge Sorting');
            for i=1:numel(unsorted_array)
                text(app.UIAxes2,(i),unsorted_array(i),num2str(unsorted_array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
        end
        %% The END of the MERGE sort algorithm.

        %% This function PERFORMS the BUCKET sort algorithm.
        function array = bucket_sort(app,array)
            x_axis = (1:length(array));
            max_array_value = max(array);               % Max element for array.
            bucket_list = zeros(1,(max_array_value + 1)); % New zeros output array.
            for j = 1:numel(array)
                bucket_list(array(j)) = (bucket_list(array(j)) + 1);
            end
            x = 1;
            for i = 1:max_array_value + 1
                for j = 1: bucket_list(i)
                    array(x) = i;
                    x = x + 1;
                    % A bar chart is created.
                    bar(app.UIAxes2,x_axis,array,"black");
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x_axis(x-1),array(x-1),"cyan")
                    hold(app.UIAxes2,"off")
                    % Speed slider to adjust bar animation speed.
                    pause((10-floor(app.SpeedSlider.Value))*0.01);
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bucket Sorting');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
        end
        %% The END of the BUCKET sort algorithm.

        %% This function PERFORMS the COUNTING sort algorithm.
        function array = counting_sort(app,array)
            x = (1:length(array));
            max1 = max(array);                  % Max element for array.
            min1 = min(array);                  % Min element for array.
            count = zeros((max1-min1+1),1);     % New empty list for count.
            for num = array
                count(num-min1+1) = count(num-min1+1) +1; % Add 1 to count.
            end
            for i=1:length(array)
                % A bar chart is created.
                bar(app.UIAxes2,x,array,"black");
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(i),array(i),"cyan");
                hold(app.UIAxes2,"off");
                % Speed slider to adjust bar animation speed.
                pause((10-floor(app.SpeedSlider.Value))*0.01);
            end
            j = 1;
            for i = (min1:max1)
                while( count(i-min1 +1) > 0)
                    array(j) = i;
                    j = j+1;
                    % A bar chart is created.
                    bar(app.UIAxes2,x,array,"black");
                    hold(app.UIAxes2,"on");
                    bar(app.UIAxes2,x(j-1),array(j-1),"magenta");
                    hold(app.UIAxes2,"off");
                    % Speed slider to adjust bar animation speed.
                    pause((10-floor(app.SpeedSlider.Value))*0.01);
                    count(i - min1 + 1) = count(i - min1 + 1) - 1;
                end
                bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Counting Sort');
                for i = 1:numel(array)
                    text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end
            end
        end
        %% The END of the COUNTING sort algorithm.

        %% This function PERFORMS the RADIX sort algorithm.
        function array = radix_sort(app,array)
            max1 = max(array);      % Max element for array.
            exp = 1;
            while max1/exp >= 1
                % Do counting sort for every digit. Note that instead of
                % passing digit number, exp is passed. exp is 10^i
                % where i is current digit number
                place = exp;
                count = zeros(1,11);            % New empty array for count.
                out = zeros(1,numel(array));    % New empty array for radix.
                for j = 1:numel(array)
                    % Store count of occurrences in count[].
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10) +1) + 1;
                end
                for i = 2:11
                    % Change count[i] so that count[i] now contains actual position of this digit in output array.
                    count(i) = count(i) + count(i-1);
                end

                for j = numel(array):-1:1
                    out(count(mod(floor(array(j)/place),10)+1)) = array(j);
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10)+1) - 1;
                    % A bar chart is created.
                    bar(app.UIAxes2,out,"black");
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,count(mod(floor(array(j)/place),10) +1)+1,out(count(mod(floor(array(j)/place),10)+1)+1),"magenta")
                    hold(app.UIAxes2,"off")
                    % Speed slider to adjust bar animation speed.
                    pause((10-floor(app.SpeedSlider.Value))*0.01);
                end
                array = out;
                exp = exp * 10;
            end
            bar(app.UIAxes2,out,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Radix Sort');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
        end
        %% The END of the RADIX sort algorithm.

        %% This function PERFORMS the QUICK sort algorithm.
        function array = quick_sort(app,array,r,p)
            if r < p
                [array,q] = partition(app,array,r,p);   % Call partition function.
                array = quick_sort(app,array,r,q-1);    % Call quick_sort function.
                array = quick_sort(app,array,q+1,p);    % Call quick_sort function.
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",...
                'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Quick Sort');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom','LineWidth',2)
            end
        end

        function [array,q] = partition(app,array,r,p)
            x=(1:length(array));
            i = r - 1;              % Smaller element's index.
            q = array(p);           % 'q' is pivot (Element to be placed at right position).
            % Small element's index to high element's index.
            for j=r:p
                % Elements is smaller than or equal to pivot.
                if array(j) < q
                    i = i+1;        % Increment smaller element's index.
                    temp1 = array(j);
                    temp2 = array(i);
                    array(j) = temp2;
                    array(i) = temp1;
                    % A bar chart is created.
                    bar(app.UIAxes2,x,array,"black");
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x(j+1),array(j+1),"blue")
                    bar(app.UIAxes2,x(j),array(j),"cyan")
                    bar(app.UIAxes2,x(i),array(i),"magenta")
                    hold(app.UIAxes2,"off")
                    % Speed slider to adjust bar animation speed.
                    pause((10-floor(app.SpeedSlider.Value))*0.01);
                end
            end
            % Swap indexs values.
            temp3 = array(i + 1) ;
            temp4 = array(p);
            array(i+1) = temp4;
            array(p) = temp3;
            q = i + 1;
        end
        %% The END of the QUICK sort algorithm.

        %% This function PERFORMS the HEAP sort algorithm.
        function array = heap_sort(app,array)
            x = 1:length(array);
            n = length(array);                  % n = length of array.
            array = max_heap_sort(app,array,n); % Call max_heap_sort func.

            % One by one extract elements.
            for i = n:-1:2
                key = array(i);                 % Swap elements.
                array(i) = array(1);
                array(1) = key;
                n = n - 1;
                array = heapify(app,array,n,1);
                % A bar chart is created.
                bar(app.UIAxes2,x,array,"black");
                hold(app.UIAxes2,"on")
                bar(app.UIAxes2,x(i),array(i),"cyan")
                hold(app.UIAxes2,"off")
                % Speed slider to adjust bar animation speed.
                pause((10-floor(app.SpeedSlider.Value))*0.01);
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Heap Sort');
            for i=1:numel(array)
                text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
        end
        % Build max heap.
        function array = max_heap_sort(app,array,n)
            % Last parent will be at ((n//2)-1) we can start at that location.
            for i = floor(n/2):-1:1
                array = heapify(app,array,n,i); % Call heapify func.
            end
        end

        function array = heapify(app,array,n,i)
            x = (1:length(array));
            l = i*2;            % left  = 2*i
            r = (i*2)+1;        % right = 2*i + 1
            % A bar chart is created.
            hold(app.UIAxes2,"on")
            bar(app.UIAxes2,x(i),array(i),"magenta")
            hold(app.UIAxes2,"off")
            % See if left child of root exists and is greater than root.
            if l <= n && array(l) > array(i)
                largest = l; % Make largest equal to 1.
            else
                largest = i; % Make largest equal to i.
            end
            % See if right child of root exists and is greater than root.
            if r <= n && array(r) > array(largest)
                largest = r;
            end
            if largest ~= i
                key = array(i);                         % Swap.
                array(i) = array(largest);
                array(largest) = key;
                array = heapify(app,array,n,largest);   % Call heapify func.
            end
        end
        %% The END of the HEAP sort algorithm.

        %% This function PERFORMS the BINARY SEARH algorithm.
        function results = binarySearch(app,array,number,index)
            midpoint = ceil(length(array)/2); % Divide the array in half.
            % If the number we are looking for is in the middle, print it.
            if array(midpoint) == number
                results = midpoint + index;
                % If the number we are looking for is greater in the midpoint:
            elseif array(midpoint) < number
                list = array(midpoint+1:length(array)); % Look to the right.
                results = binarySearch(app,list,number,midpoint + index);
                % If the number we are looking for is smaller in the midpoint:
            else
                list = array(1:midpoint-1);             % Look to the left.
                results = binarySearch(app,list,number,index);
            end
        end
        %% The END of the BINARY SEARCH algorithm.

        % Voice recognition.
        function results = voice(~,String)
            caUserInput = {String};
            caUserInput = char(caUserInput);
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            Speak(obj, caUserInput)
        end

        %% Insertion sort algorithm function.
        function unsorted_array = Insertion(~,unsorted_array)
            length = size(unsorted_array,2);
            for i = 2:length
                key = unsorted_array(i);
                j = i - 1;
                while j >0 && unsorted_array(j)> key
                    unsorted_array(j+1) = unsorted_array(j);
                    j = j - 1;
                end
                unsorted_array(j+1) = key;
            end
        end

        %% Enable and disable button functions.
        function enable_button(app)
            app.QUICKSORTButton.Enable="on";
            app.INSERTIONSORTButton.Enable="on";
            app.BUBBLESORTButton.Enable="on";
            app.RADIXSORTButton.Enable="on";
            app.MERGESORTButton.Enable="on";
            app.COUNTINGSORTButton.Enable="on";
            app.BUCKETSORTButton.Enable="on";
            app.HEAPSORTButton.Enable="on";
            app.CREATEARRAYButton.Enable="on";
            app.CLEARButton.Enable="on";
        end
        function disable_button(app)
            app.QUICKSORTButton.Enable="off";
            app.INSERTIONSORTButton.Enable="off";
            app.BUBBLESORTButton.Enable="off";
            app.RADIXSORTButton.Enable="off";
            app.MERGESORTButton.Enable="off";
            app.COUNTINGSORTButton.Enable="off";
            app.BUCKETSORTButton.Enable="off";
            app.HEAPSORTButton.Enable="off";
            app.CREATEARRAYButton.Enable="off";
            app.CLEARButton.Enable="off";
        end
        %% The END of the enable and disable button functions.
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RANDOMARRAYButton
        function RANDOMARRAYButtonPushed(app, event)
            try
                is_same = app.Switch.Value;
                array_size = app.ArraySizeEditField.Value;
                upper_bound = app.UpperBoundsEditField.Value;
                lower_bound = app.LowerBoundsEditField.Value;
                if app.SeedCheckBox.Value == 0
                    if (upper_bound == 0 && lower_bound == 0) || array_size == 0
                        msgbox('The size of the array must be greater than 0. Please check your array limitations.','Error','error')
                    else
                        if lower_bound >= upper_bound
                            msgbox('The upper limit must be greater than the lower limit.','Error','error')
                        else
                            if strcmp(is_same,'No')
                                if (abs(upper_bound-lower_bound)<array_size)
                                    msgbox('To get a unique array, the difference between the lower and upper bound must be greater than the length of the array.','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([lower_bound upper_bound],1,1)];
                                        % unique(A) returns the same data as in A, but with no repetitions.
                                        app.unsorted_array = unique(app.unsorted_array);

                                        if length(app.unsorted_array) == array_size
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                app.unsorted_array=randi([lower_bound upper_bound],1,array_size);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.29,0.02,0.02]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Visualisation');
                            for i=1:numel(app.unsorted_array)
                                text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                    'HorizontalAlignment','center',...
                                    'VerticalAlignment','bottom')
                            end
                        end
                    end
                else
                    rng('default');
                    rng(100);
                    if (upper_bound == 0  && lower_bound == 0) || array_size == 0
                        msgbox('The size of the array must be greater than 0. Please check your array limitations.','Error','error')
                    else
                        if lower_bound >= upper_bound
                            msgbox('The upper limit must be greater than the lower limit.','Error','error')
                        else
                            if strcmp(is_same,'No')
                                if (abs(upper_bound-lower_bound)<array_size)
                                    msgbox('To get a unique array, the difference between the lower and upper bound must be greater than the length of the array.','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([lower_bound upper_bound],1,1)];
                                        app.unsorted_array = unique(app.unsorted_array);

                                        if length(app.unsorted_array) == array_size
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                app.unsorted_array=randi([lower_bound upper_bound],1,array_size);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Visualisation');
                            for i=1:numel(app.unsorted_array)
                                text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                    'HorizontalAlignment','center',...
                                    'VerticalAlignment','bottom')
                            end
                        end
                    end
                end
            catch
                msgbox('Please, check it array', 'Error','error');
            end
        end

        % Button pushed function: CREATEARRAYButton
        function CREATEARRAYButtonPushed(app, event)
            try
                array = app.EditField.Value;
                app.unsorted_array = [];
                temp = erase(array,{'[',']'});
                array = split(temp,{' ',','});

                app.unsorted_array = str2double(array);
                bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Sort Algorithm Visualisation');
                for i=1:numel(app.unsorted_array)
                    text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end
            catch
                msgbox('Please check the entered array.', 'Error','error');
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.STOPButton.Text == "STOP"
                app.STOPButton.Text = "CONTINUE";
                app.Lamp.Color = 'green';
                % Block program execution and wait to resume
                uiwait(app.UIFigureOzan);
                app.Lamp.Color = 'red';
            else
                app.STOPButton.Text = "STOP";
                uiresume(app.UIFigureOzan);
            end
            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"cyan","EdgeColor","black",'LineWidth',1.5)
            for i = 1:numel(app.unsorted_array)
                text(app.UIAxes2,i,app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                    'HorizontalAlignment','center','VerticalAlignment','bottom')
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)

            cla(app.UIAxes2)            % Clear axes.
            app.array_size.Value = 0;
            app.lower_bound.Value = 0;
            app.upper_bound.Value = 0;
            app.EditField.Value = '';
            app.SeedCheckBox.Value = 0;
            app.unsorted_array = [];
        end

        % Button pushed function: SKIPButton
        function SKIPButtonPushed(app, event)
            cla(app.UIAxes2)
            app.unsorted_array = sort(app.unsorted_array);
            hold(app.UIAxes2,"on")
            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2)
            hold(app.UIAxes2,"off")
            for i=1:numel(app.unsorted_array)
                text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom')
            end
            enable_button(app)
            while true
                pause(1.)
            end
        end

        % Button pushed function: INSERTIONSORTButton
        function INSERTIONSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                insertion_sorting(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Button pushed function: BUBBLESORTButton
        function BUBBLESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                bubble_sorting(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: MERGESORTButton
        function MERGESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                Merge_Sort(app,app.unsorted_array,1,length(app.unsorted_array))
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: BUCKETSORTButton
        function BUCKETSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else

                disable_button(app)
                bucket_sort(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: HEAPSORTButton
        function HEAPSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                heap_sort(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: QUICKSORTButton
        function QUICKSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                quick_sort(app,app.unsorted_array,1,length(app.unsorted_array))
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: RADIXSORTButton
        function RADIXSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                radix_sort(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: COUNTINGSORTButton
        function COUNTINGSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Array is empty please set an array.','Error','error')
            else
                disable_button(app)
                counting_sort(app,app.unsorted_array)
                enable_button(app)
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureOzan = uifigure('Visible', 'off');
            app.UIFigureOzan.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigureOzan);
            app.MainScreenTab = uitab(app.TabGroup);
            app.MainScreenTab.Title = 'Main Screen';
            app.CreatedbyOrhanOzanYldzLabel = uilabel(app.MainScreenTab);
            app.CreatedbyOrhanOzanYldzLabel.Text = 'Created by Orhan Ozan Yıldız.';
            app.Image = uiimage(app.MainScreenTab);
            app.Image.ImageSource = 'icon_feed_back.png';
            app.Image2 = uiimage(app.MainScreenTab);
            app.Image2.ImageSource = 'icon_information.png';
            app.Image_2 = uiimage(app.MainScreenTab);
            app.Image_2.ImageSource = 'university_logo.png';
            app.SortingTab = uitab(app.TabGroup);
            app.SortingTab.Title = 'Sorting';
            app.UIAxes2 = uiaxes(app.SortingTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.CreatearandomarrayLabel = uilabel(app.SortingTab);
            app.CreatearandomarrayLabel.Text = 'Create a random array:';
            app.SeedCheckBox = uicheckbox(app.SortingTab);
            app.SeedCheckBox.Text = 'Seed (100)';
            app.EditField = uieditfield(app.SortingTab, 'text');
            app.CREATEARRAYButton = uibutton(app.SortingTab, 'push');
            app.CREATEARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEARRAYButtonPushed, true);
            app.CREATEARRAYButton.Text = 'CREATE ARRAY';
            app.Createmanuelarrayie2416Label = uilabel(app.SortingTab);
            app.Createmanuelarrayie2416Label.Text = 'Create manuel array(i.e.: 2,4,1,6,...):';
            app.RANDOMARRAYButton = uibutton(app.SortingTab, 'push');
            app.RANDOMARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RANDOMARRAYButtonPushed, true);
            app.RANDOMARRAYButton.Text = 'RANDOM ARRAY';
            app.SpeedSliderLabel = uilabel(app.SortingTab);
            app.SpeedSliderLabel.Text = 'Speed Slider';
            app.SpeedSlider = uislider(app.SortingTab);
            app.STOPButton = uibutton(app.SortingTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CLEARButton = uibutton(app.SortingTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SKIPButton = uibutton(app.SortingTab, 'push');
            app.SKIPButton.ButtonPushedFcn = createCallbackFcn(app, @SKIPButtonPushed, true);
            app.SKIPButton.Text = 'SKIP';
            app.Lamp = uilamp(app.SortingTab);
            app.INSERTIONSORTButton = uibutton(app.SortingTab, 'push');
            app.INSERTIONSORTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTIONSORTButtonPushed, true);
            app.INSERTIONSORTButton.Text = 'INSERTION SORT';
            app.BUBBLESORTButton = uibutton(app.SortingTab, 'push');
            app.BUBBLESORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUBBLESORTButtonPushed, true);
            app.BUBBLESORTButton.Text = 'BUBBLE SORT';
            app.MERGESORTButton = uibutton(app.SortingTab, 'push');
            app.MERGESORTButton.ButtonPushedFcn = createCallbackFcn(app, @MERGESORTButtonPushed, true);
            app.MERGESORTButton.Text = 'MERGE SORT';
            app.QUICKSORTButton = uibutton(app.SortingTab, 'push');
            app.QUICKSORTButton.ButtonPushedFcn = createCallbackFcn(app, @QUICKSORTButtonPushed, true);
            app.QUICKSORTButton.Text = 'QUICK SORT';
            app.HEAPSORTButton = uibutton(app.SortingTab, 'push');
            app.HEAPSORTButton.ButtonPushedFcn = createCallbackFcn(app, @HEAPSORTButtonPushed, true);
            app.HEAPSORTButton.Text = 'HEAP SORT';
            app.RADIXSORTButton = uibutton(app.SortingTab, 'push');
            app.RADIXSORTButton.ButtonPushedFcn = createCallbackFcn(app, @RADIXSORTButtonPushed, true);
            app.RADIXSORTButton.Text = 'RADIX SORT';
            app.COUNTINGSORTButton = uibutton(app.SortingTab, 'push');
            app.COUNTINGSORTButton.ButtonPushedFcn = createCallbackFcn(app, @COUNTINGSORTButtonPushed, true);
            app.COUNTINGSORTButton.Text = 'COUNTING SORT';
            app.BUCKETSORTButton = uibutton(app.SortingTab, 'push');
            app.BUCKETSORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUCKETSORTButtonPushed, true);
            app.BUCKETSORTButton.Text = 'BUCKET SORT';
            app.TIMECOMPARISONButton = uibutton(app.SortingTab, 'push');
            app.TIMECOMPARISONButton.Text = 'TIME COMPARISON';
            app.Switch = uiswitch(app.SortingTab, 'toggle');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.Value = 'No';
            app.DifferentNumbersSwitchLabel = uilabel(app.SortingTab);
            app.DifferentNumbersSwitchLabel.Text = 'Different Numbers ';
            app.ArraySizeEditFieldLabel = uilabel(app.SortingTab);
            app.ArraySizeEditFieldLabel.Text = 'Array Size';
            app.ArraySizeEditField = uieditfield(app.SortingTab, 'numeric');
            app.LowerBoundsEditFieldLabel = uilabel(app.SortingTab);
            app.LowerBoundsEditFieldLabel.Text = 'Lower Bounds';
            app.LowerBoundsEditField = uieditfield(app.SortingTab, 'numeric');
            app.UpperBoundsEditFieldLabel = uilabel(app.SortingTab);
            app.UpperBoundsEditFieldLabel.Text = 'Upper Bounds';
            app.UpperBoundsEditField = uieditfield(app.SortingTab, 'numeric');
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.UIAxes = uiaxes(app.ComparisonTab);
            title(app.UIAxes, 'TIME COMPARISON')
            xlabel(app.UIAxes, 'Array Length')
            ylabel(app.UIAxes, 'Time')
            app.INSERTIONSORTCheckBox_2 = uicheckbox(app.ComparisonTab);
            app.INSERTIONSORTCheckBox_2.Text = 'INSERTION SORT';
            app.BUBBLESORTCheckBox = uicheckbox(app.ComparisonTab);
            app.BUBBLESORTCheckBox.Text = 'BUBBLE SORT';
            app.MERGESORTCheckBox = uicheckbox(app.ComparisonTab);
            app.MERGESORTCheckBox.Text = 'MERGE SORT';
            app.HEAPSORTCheckBox = uicheckbox(app.ComparisonTab);
            app.HEAPSORTCheckBox.Text = 'HEAP SORT';
            app.BUCKETSORTCheckBox = uicheckbox(app.ComparisonTab);
            app.BUCKETSORTCheckBox.Text = 'BUCKET SORT';
            app.QUICKSORTCheckBox = uicheckbox(app.ComparisonTab);
            app.QUICKSORTCheckBox.Text = 'QUICK SORT';
            app.RADIXSORTCheckBox = uicheckbox(app.ComparisonTab);
            app.RADIXSORTCheckBox.Text = 'RADIX SORT';
            app.COUNTINGSORTCheckBox = uicheckbox(app.ComparisonTab);
            app.COUNTINGSORTCheckBox.Text = 'COUNTING SORT';
            app.COMPARESELECTEDMETHODSButton = uibutton(app.ComparisonTab, 'push');
            app.COMPARESELECTEDMETHODSButton.Text = 'COMPARE SELECTED METHODS ';
            app.CLEARGRAPHButton = uibutton(app.ComparisonTab, 'push');
            app.CLEARGRAPHButton.Text = 'CLEAR GRAPH';
            app.IterationEditField_2Label = uilabel(app.ComparisonTab);
            app.IterationEditField_2Label.Text = 'Iteration';
            app.IterationEditField_2 = uieditfield(app.ComparisonTab, 'numeric');
            app.UpperBoundEditField_2Label = uilabel(app.ComparisonTab);
            app.UpperBoundEditField_2Label.Text = 'Upper Bound';
            app.UpperBoundEditField_2 = uieditfield(app.ComparisonTab, 'numeric');
            app.NumberOfElementsEditFieldLabel = uilabel(app.ComparisonTab);
            app.NumberOfElementsEditFieldLabel.Text = 'Number Of Elements';
            app.NumberOfElementsEditField = uieditfield(app.ComparisonTab, 'numeric');
            app.COMPAREALLMETHODSButton = uibutton(app.ComparisonTab, 'push');
            app.COMPAREALLMETHODSButton.Text = 'COMPARE ALL METHODS ';
            app.MatrixMultiplicaitonTab = uitab(app.TabGroup);
            app.MatrixMultiplicaitonTab.Title = 'Matrix Multiplicaiton';
            app.FibonacciSequanceTab = uitab(app.TabGroup);
            app.FibonacciSequanceTab.Title = 'Fibonacci Sequance';
            app.BinarySearchTab = uitab(app.TabGroup);
            app.BinarySearchTab.Title = 'Binary Search';
            app.RandomizedSelectionTab = uitab(app.TabGroup);
            app.RandomizedSelectionTab.Title = 'Randomized Selection';
            % Show the figure after all components are created
            app.UIFigureOzan.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_algorithm_ozan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureOzan)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureOzan)
        end
    end
end