classdef main_gui_algorithms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DASHBOARDTab                   matlab.ui.container.Tab
        Image4                         matlab.ui.control.Image
        UniversityLogo                 matlab.ui.control.Image
        FeedBackImage                  matlab.ui.control.Image
        Image5_2                       matlab.ui.control.Image
        SORTINGTab                     matlab.ui.container.Tab
        ImageSaveDataSorting           matlab.ui.control.Image
        ImageHelpSortingVisualisation  matlab.ui.control.Image
        ImageSortingExcel              matlab.ui.control.Image
        PAUSEButton_2                  matlab.ui.control.StateButton
        IncreaseMaxValueButton         matlab.ui.control.Image
        IncreaseLengthButton           matlab.ui.control.Image
        DecreaseMaxValueButton         matlab.ui.control.Image
        DecreaseLengthButton           matlab.ui.control.Image
        STARTButton                    matlab.ui.control.Button
        SKIPButton                     matlab.ui.control.Button
        CLEARButton                    matlab.ui.control.Button
        SPEEDSlider                    matlab.ui.control.Slider
        SPEEDSliderLabel               matlab.ui.control.Label
        MAXIMUMVALUEOFARRAYKnob        matlab.ui.control.Knob
        MAXIMUMVALUEOFARRAYKnobLabel   matlab.ui.control.Label
        SORTINGTYPESKnob               matlab.ui.control.DiscreteKnob
        SORTINGTYPESKnobLabel          matlab.ui.control.Label
        LENGTHOFARRAYKnob              matlab.ui.control.Knob
        LENGTHOFARRAYKnobLabel         matlab.ui.control.Label
        UIAxesVisualisation            matlab.ui.control.UIAxes
        TIMECOMPARISONTab              matlab.ui.container.Tab
        Image_2                        matlab.ui.control.Image
        ArrayNumberEditField           matlab.ui.control.NumericEditField
        ArrayNumberEditFieldLabel      matlab.ui.control.Label
        ArraySzieEditFieldLabel        matlab.ui.control.Label
        MaxRangeEditFieldLabel         matlab.ui.control.Label
        ArraySizeEditField             matlab.ui.control.NumericEditField
        RangeEditField                 matlab.ui.control.NumericEditField
        ClearGraphButton               matlab.ui.control.Button
        CompareSelectedButton          matlab.ui.control.Button
        CompareAllButton               matlab.ui.control.Button
        BubbleSortCheckBox             matlab.ui.control.CheckBox
        InsertionSortCheckBox          matlab.ui.control.CheckBox
        MergeSortCheckBox              matlab.ui.control.CheckBox
        HeapSortCheckBox               matlab.ui.control.CheckBox
        QuickSortCheckBox              matlab.ui.control.CheckBox
        BucketSortCheckBox             matlab.ui.control.CheckBox
        RadixSortCheckBox              matlab.ui.control.CheckBox
        CountingSortCheckBox           matlab.ui.control.CheckBox
        UIAxesTimeComparison           matlab.ui.control.UIAxes
        BINARYSEARCHTab                matlab.ui.container.Tab
        BINARYSEARCHLabel              matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        ClearFieldButton               matlab.ui.control.Button
        FindIndexButton                matlab.ui.control.Button
        SetRandomArrayButton           matlab.ui.control.Button
        UpperBoundEditField            matlab.ui.control.NumericEditField
        UpperBoundLabel                matlab.ui.control.Label
        LowerBoundLabel                matlab.ui.control.Label
        ArraySizeLabel                 matlab.ui.control.Label
        LowerBoundEditField            matlab.ui.control.NumericEditField
        ArraySizeEditField_2           matlab.ui.control.NumericEditField
        GenerateRandomArrayButton      matlab.ui.control.Button
        unsorted_array_binary_search   matlab.ui.control.TextArea
        displayindex                   matlab.ui.control.TextArea
        take_number                    matlab.ui.control.EditField
        SortRandomArrayButton          matlab.ui.control.Button
        sorted_array                   matlab.ui.control.TextArea
        UIAxesSortedArray              matlab.ui.control.UIAxes
        UIAxesUnsortedArray            matlab.ui.control.UIAxes
        MATRIXOPERATIONSTab            matlab.ui.container.Tab
        MatrixOperationsLabel          matlab.ui.control.Label
        FirstMatrixLabel               matlab.ui.control.Label
        col2                           matlab.ui.control.NumericEditField
        SecondMatrixColumnLabel        matlab.ui.control.Label
        row2                           matlab.ui.control.NumericEditField
        SecondMatrixRowLabel           matlab.ui.control.Label
        ButtonMinus                    matlab.ui.control.Button
        ButtonAdd                      matlab.ui.control.Button
        CreateManualMatrixButton       matlab.ui.control.Button
        CreateRandomMatrixButton       matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        ImageInformationMatrixMux      matlab.ui.control.Image
        col1                           matlab.ui.control.NumericEditField
        FirstMatrixColumnLabel         matlab.ui.control.Label
        row1                           matlab.ui.control.NumericEditField
        FirstMatrixRowLabel            matlab.ui.control.Label
        Image12_3                      matlab.ui.control.Image
        ImageSecondMatrixExcel         matlab.ui.control.Image
        ImageFirstMatrixExcel          matlab.ui.control.Image
        ResultMatrixLabel              matlab.ui.control.Label
        SecondMatrixLabel              matlab.ui.control.Label
        ButtonMultiply                 matlab.ui.control.Button
        UITableFinalMatrix             matlab.ui.control.Table
        UITableSecondMatrix            matlab.ui.control.Table
        UITableFirstMatrix             matlab.ui.control.Table
        Image5                         matlab.ui.control.Image
        FIBONACCITab                   matlab.ui.container.Tab
        ImageInformationMatrixMux_2    matlab.ui.control.Image
        ithnumberisLabel               matlab.ui.control.Label
        FIBONACCISEQUENCELabel         matlab.ui.control.Label
        ClearButton_2                  matlab.ui.control.Button
        FibonacciArrayLabel            matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        TextArea                       matlab.ui.control.TextArea
        EditField2                     matlab.ui.control.EditField
        FindithFibonacciNumberButton   matlab.ui.control.Button
        UIAxesFibonacci                matlab.ui.control.UIAxes
        RANDOMIZEDSELECTIONTab         matlab.ui.container.Tab
        array_size_randomized          matlab.ui.control.Spinner
        ArraySizeSpinnerLabel          matlab.ui.control.Label
        ImageInformationMatrixMux_3    matlab.ui.control.Image
        UnsortedArrayLabel             matlab.ui.control.Label
        TextArea_unsorted_randomized   matlab.ui.control.TextArea
        SortedArrayLabel               matlab.ui.control.Label
        TextArea_sorted_randomized     matlab.ui.control.TextArea
        ithsmallestnumberLabel         matlab.ui.control.Label
        IndexLabel                     matlab.ui.control.Label
        ClearButtonRandomized          matlab.ui.control.Button
        EditFieldIndex                 matlab.ui.control.NumericEditField
        TextAreaNumber                 matlab.ui.control.TextArea
        ShowithSmallestButton          matlab.ui.control.Button
        DontcontainthesamenumbersCheckBox  matlab.ui.control.CheckBox
        DefaultArrayButtonRandomized   matlab.ui.control.Button
        RandomArrayButtonRandomized    matlab.ui.control.Button
        lower_bound_randomized         matlab.ui.control.Spinner
        LowerBoundSpinnerLabel         matlab.ui.control.Label
        upper_bound_randomized         matlab.ui.control.Spinner
        UpperBoundSpinnerLabel         matlab.ui.control.Label
        RANDOMIZEDSELECTIONLabel       matlab.ui.control.Label
        UIAxesSortedRandomized         matlab.ui.control.UIAxes
        UIAxesUnsortedRandomized       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        arrayRange;
        arrayNumber;
        random_array;
        unsortedarray;
        unsorted_array;
        % Sortings.
        insertion=[]
        bubble=[]
        merge=[]
        bucket=[]
        heap=[]
        radix=[]
        counting=[]
        quick=[]
        % Elapsed times.
        time_of_insertion = zeros();
        time_of_bubble = zeros();
        time_of_merge = zeros();
        time_of_bucket = zeros();
        time_of_radix = zeros();
        time_of_counting = zeros();
        time_of_quick = zeros();
        time_of_heap = zeros();
    end
    methods (Access = private)

        function Time_Comparison(app,currentvalue,range,number_of_array,iteration)
            rng('default');
            rng(100);
            range_of_array = range;             % Define range of array.
            number_of_runs = number_of_array;   % Define number of elements.
            iteration_number = iteration;       % Define iteration number.
            
            cla(app.UIAxesTimeComparison);      % Clear Time comparison graph.
            list_of_legend=[];
            % Create empty array of sorting algorithms.
            app.insertion=[];
            app.bubble=[];
            app.merge=[];
            app.bucket=[];
            app.heap=[];
            app.radix=[];
            app.counting=[];
            app.quick=[];
            
            % Add 10 each time to number_of_runs until you reach the iteration_number.
            for j = number_of_runs:10:iteration_number
                % for i from 1 to 30 upcount +1.
                for i=1:30
                    randomarray = randperm(range_of_array,j);   % Create random array.
                    max_random_array = max(randomarray);        % Find the max value.

                    %% The elapsed time in Insertion Sort.
                    if currentvalue(1)
                        
                        tic;                            % Start stopwatch timer.
                        insertion_sort(randomarray);    % insertion sort.
                        insertion_sort_time = toc;      % Stop stopwatch timer.
                        % insertion_sort_time assigned to
                        % from time_of_insertion_sort(1,number_of_array) to
                        % time_of_insertion_sort(30,number_of_array). 
                        app.time_of_insertion(i,j) = insertion_sort_time;
                        sum_of_insertion_sort = sum(app.time_of_insertion);
                        averageof_insertionsort = sum_of_insertion_sort / 30 ;
                    end

                    %% The elapsed time in Bubble Sort.
                    if currentvalue(2)
                        tic;
                        bubble_sort(randomarray);
                        bubble_sort_time = toc;
                        app.time_of_bubble(i,j) = bubble_sort_time;
                        sum_of_bubble_sort = sum(app.time_of_bubble);
                        averageof_bubblesort = sum_of_bubble_sort / 30 ;
                    end

                    %% The elapsed time in Merge Sort.
                    if currentvalue(3)
                        tic;
                        merge_sorti(randomarray);
                        merge_sort_time = toc;
                        app.time_of_merge(i,j) = merge_sort_time;
                        sum_of_merge_sort = sum(app.time_of_merge);
                        averageof_mergesort = sum_of_merge_sort / 30;
                    end

                    %% The elapsed time in Bucket Sort.
                    if currentvalue(4)
                        tic;
                        bucket_sort(randomarray);
                        bucket_sort_time = toc;
                        app.time_of_merge(i,j) = bucket_sort_time;
                        sum_of_bucket_sort = sum(app.time_of_merge);
                        averageof_bucketsort = sum_of_bucket_sort / 30 ;
                    end

                    %% The elapsed time in Radix Sort.
                    if currentvalue(5)
                        tic;
                        radix_sort(randomarray);
                        radix_sort_time = toc;
                        app.time_of_radix(i,j) = radix_sort_time;
                        sum_of_radix_sort = sum(app.time_of_radix);
                        averageof_radixsort = sum_of_radix_sort / 30 ;
                    end

                    %% The elapsed time in Counting Sort.
                    if currentvalue(6)
                        tic;
                        counting_sort(randomarray,max_random_array);
                        counting_sort_time = toc;
                        app.time_of_counting(i,j) = counting_sort_time;
                        sum_of_counting_sort = sum(app.time_of_counting);
                        averageof_countingsort = sum_of_counting_sort / 30;
                    end

                    %% The elapsed time in Quick Sort.
                    if currentvalue(7)
                        tic;
                        quicksorti(randomarray,1,length(randomarray));
                        quick_sort_time = toc;
                        app.time_of_quick(i,j) = quick_sort_time;
                        sum_of_quick_sort = sum(app.time_of_quick);
                        averageof_quicksort = sum_of_quick_sort / 30;
                    end

                    %% The elapsed time in Heap Sort.
                    if currentvalue(8)
                        tic;
                        heap_sort(randomarray);
                        heap_sort_time = toc;
                        app.time_of_heap(i,j) = heap_sort_time;
                        sum_of_heap_sort = sum(app.time_of_heap);
                        averageof_heapsort = sum_of_heap_sort / 30;
                    end
                end
                % If 1 is selected in current_value, averageof_insertion_sort(1,j) 
                % is added to app.insertion.
                if currentvalue(1)
                    app.insertion=[app.insertion averageof_insertionsort(1,j)];
                end
                if currentvalue(2)
                    app.bubble=[app.bubble averageof_bubblesort(1,j)];
                end
                if currentvalue(3)
                    app.merge=[app.merge averageof_mergesort(1,j)];
                end
                if currentvalue(4)
                    app.bucket=[app.bucket averageof_bucketsort(1,j)];
                end
                if currentvalue(5)
                    app.radix=[app.radix averageof_radixsort(1,j)];
                end
                if currentvalue(6)
                    app.counting=[app.counting averageof_countingsort(1,j)];
                end
                if currentvalue(7)
                    app.quick=[app.quick averageof_quicksort(1,j)];
                end
                if currentvalue(8)
                    app.heap=[app.heap averageof_heapsort(1,j)];
                end
            end
            % Add 10 each time to number_of_runs until you reach the iteration_number.
            k = number_of_runs:10:iteration_number;
            % Insertion sort if current value is '1'.
            if currentvalue(1)
                hold(app.UIAxesTimeComparison,"on");               % Retain current plot when adding new plots.
                plot(app.UIAxesTimeComparison,k,app.insertion,'r') % 2-D line plot.
                list_of_legend=[list_of_legend "Insertion"];       % Add "Insertion" to list_of_legend.
            end

            if currentvalue(2)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.bubble,"b");
                list_of_legend=[list_of_legend "Bubble"];
            end

            if currentvalue(3)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.merge,"k");
                list_of_legend=[list_of_legend "Merge"];
            end
            if currentvalue(4)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.bucket,"m");
                list_of_legend=[list_of_legend "Bucket"];
            end
            if currentvalue(5)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.radix,"r");
                list_of_legend=[list_of_legend "Radix"];
            end
            if currentvalue(6)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.counting,"g");
                list_of_legend=[list_of_legend "Counting"];
            end
            if currentvalue(7)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.quick,"c");
                list_of_legend=[list_of_legend "Quick"];
            end

            if currentvalue(8)
                hold(app.UIAxesTimeComparison,"on");
                plot(app.UIAxesTimeComparison,k,app.heap,"b");
                list_of_legend=[list_of_legend "Heap"];
            end
            legend(app.UIAxesTimeComparison,list_of_legend); % Add legend to axes.
        end

        function array = insertion_sort(~,array)
            %% Making a for loop because of to swap an item with previous one, starting from 2.
            for j=2:length(array)
                % Key will be used for comparison with previous items and
                % sent to the place it belongs.
                key= array(j);
                i = j-1;
                % While loop for comparing the current element with the
                % sorted portion and swapping.
                while i>0 && array(i)>key
                    % Moving the bigger item 1 step right to make place for
                    % key.
                    array(i+1) = array(i);
                    i=i-1;
                end
                % Take array[j] all the way left to the place where it has
                % a smaller/no value to its left.
                array(i+1)=key;
            end
        end

        function clear(app)
            app.ArraySizeEditField_2.Value=0;
            app.UpperBoundEditField.Value=0;
            app.LowerBoundEditField.Value=0;
            app.unsorted_array_binary_search.Value='';
            app.sorted_array.Value='';
            app.unsortedarray=[];
            cla(app.UIAxesUnsortedArray)
            cla(app.UIAxesSortedArray)
            app.take_number.Value='';
            app.displayindex.Value='';
        end

        %         function Temp_call(src,~)
        %             str = get(src, 'String');
        %             if isnan(str2double(str))
        %                 set(src, 'String',' 0');
        %                 warndlg('Input must be numerical');
        %             end
        %         end

        function fib_array = Fibonacci(app,number,points)
            fib_array = [0 1];
            for i=3:number
                fib_array = [fib_array fib_array(i-1)+fib_array(i-2)];
                points.YData=fib_array;
                app.TextArea.Value = num2str(fib_array);
                for i1=1:numel(fib_array)
                    text(app.UIAxesFibonacci,(i1),fib_array(i1),num2str(fib_array(i1),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end
                pause(0.3)
                drawnow
            end

        end

        function [ith_smallest] = qselect(~,array, k)
            low = 1;
            high = length(array);

            while(true)
                median = fix((low+high)/2);
                if (array(median) < array(high))
                    temp = array(median) ;
                    array(median) = array(high);
                    array(high) = temp ;
                end
                if (array(median) < array(low))
                    temp = array(median) ;
                    array(median) = array(low);
                    array(low) = temp ;
                end
                if (array(high) < array(low))
                    temp = array(high) ;
                    array(high) = array(low);
                    array(low) = temp ;
                end
                index = array (high) ;
                i = low;
                for j = low:high-1
                    if(array(j)<= index)
                        temp = array(i);
                        array(i) = array(j);
                        array(j) = temp;
                        i = i+1;
                    end
                end
                temp = array(high);
                array(high)= array(i);
                array(i)=temp;
                median=i-low+1;
                if(k<median)
                    high =i-1;
                elseif(k>median)
                    low =i+1;
                    k=k-median;
                else
                    ith_smallest = array(i);
                    return;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LENGTHOFARRAYKnob
        function LENGTHOFARRAYKnobValueChanged(app, event)
            global chooselength
            global choosemaximum
            global unsortedvariables

            chooselength = app.LENGTHOFARRAYKnob.Value;
            choosemaximum = app.MAXIMUMVALUEOFARRAYKnob.Value;

            if choosemaximum > 1 && chooselength > 1
                unsortedvariables = randi(ceil(choosemaximum),1,ceil(chooselength));
                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,...
                    num2str(unsortedvariables'),'HorizontalAlignment','center',...
                    'VerticalAlignment','baseline','Color','r','FontWeight','bold')
            end


        end

        % Value changed function: MAXIMUMVALUEOFARRAYKnob
        function MAXIMUMVALUEOFARRAYKnobValueChanged(app, event)
            global chooselength
            global choosemaximum
            global unsortedvariables
            chooselength = app.LENGTHOFARRAYKnob.Value;
            choosemaximum = app.MAXIMUMVALUEOFARRAYKnob.Value;

            if choosemaximum > 1 && chooselength > 1
                unsortedvariables=randi(ceil(choosemaximum),1,ceil(chooselength));

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,...
                    num2str(unsortedvariables'),'HorizontalAlignment','center',...
                    'VerticalAlignment','baseline','Color','r','FontWeight','bold')
            end
        end

        % Image clicked function: DecreaseLengthButton
        function DecreaseLengthButtonImageClicked(app, event)
            app.LENGTHOFARRAYKnob.Limits = app.LENGTHOFARRAYKnob.Limits/10;
        end

        % Callback function
        function IncreaseLengthButtonImageClicked(app, event)
            app.LENGTHOFARRAYKnob.Limits = app.LENGTHOFARRAYKnob.Limits*10;
        end

        % Callback function
        function IncreaseLengthButton_2ImageClicked(app, event)
            app.MAXIMUMVALUEOFARRAYKnob.Limits=app.MAXIMUMVALUEOFARRAYKnob.Limits*10;
        end

        % Image clicked function: DecreaseMaxValueButton
        function DecreaseMaxValueButtonImageClicked(app, event)
            app.MAXIMUMVALUEOFARRAYKnob.Limits=app.MAXIMUMVALUEOFARRAYKnob.Limits/10;
        end

        % Value changed function: SPEEDSlider
        function SPEEDSliderValueChanged(app, event)
            global projectspeed

            projectspeed = 0.01*(100 - app.SPEEDSlider.Value);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global unsortedvariables projectspeed
            projectspeed = 0.01*(100 - app.SPEEDSlider.Value);

            %% This function PERFORMS the Quick sort algorithm.
            if app.SORTINGTYPESKnob.Value == num2str(5)

                unsortedvariables = QuickSort2(unsortedvariables,1,length(unsortedvariables),app);

                bar(app.UIAxesVisualisation,unsortedvariables)

                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')

                drawnow update
                %% This function PERFORMS the BUBBLE sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(3)

                hasChanged = true;
                itemCount = numel(unsortedvariables);

                while(hasChanged)

                    hasChanged = false;
                    itemCount = itemCount - 1;

                    for index = (1:itemCount)

                        if(unsortedvariables(index) > unsortedvariables(index+1))
                            unsortedvariables([index index+1]) = unsortedvariables([index+1 index]); %swap
                            hasChanged = true;
                        end
                        pause(projectspeed)
                        b = bar(app.UIAxesVisualisation,unsortedvariables,'FaceColor','flat','EdgeColor',[0 1 0],'LineWidth',1.5);
                        b.CData(index,:) = [1 0 0];
                        b.CData(index+1,:) = [1 0 0];
                        text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                            'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                        drawnow update
                    end
                end

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update

                %% This function PERFORMS the INSERTION sort algorithm.
            elseif app.SORTINGTYPESKnob.Value==num2str(1)
                pause(0.1)
                for count = (2:numel(unsortedvariables))

                    anothercount = count;
                    pause(projectspeed)
                    b = bar(app.UIAxesVisualisation,unsortedvariables,'FaceColor','flat','EdgeColor',[0 1 0],'LineWidth',1.5);
                    b.CData(anothercount,:) = [1 0 0];
                    b.CData(anothercount-1,:) = [1 0 0];
                    text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                        'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                    drawnow update

                    while (anothercount > 1) && (unsortedvariables(anothercount) < unsortedvariables(anothercount-1))

                        temp = unsortedvariables(anothercount);
                        unsortedvariables(anothercount) = unsortedvariables(anothercount-1);
                        unsortedvariables(anothercount-1) = temp;

                        pause(projectspeed)
                        b = bar(app.UIAxesVisualisation,unsortedvariables,'FaceColor','flat','EdgeColor',[0 1 0],'LineWidth',1.5);
                        b.CData(anothercount,:) = [1 0 0];
                        b.CData(anothercount-1,:) = [1 0 0];
                        text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                            'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                        drawnow update

                        anothercount=anothercount-1;
                    end
                end

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')

                drawnow update

                %% This function PERFORMS the HEAP sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(4)

                unsortedvariables = HeapSort(unsortedvariables, app, event);

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update

                %% This function PERFORMS the BUCKET sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(8)

                unsortedvariables = bucketsortfunction(unsortedvariables, app);

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update

                %% This function PERFORMS the COUNT sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(6)

                unsortedvariables = CountSort(unsortedvariables,app, event);

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update

                %% This function PERFORMS the RADIX sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(7)

                unsortedvariables = radixsortfunction(unsortedvariables,app);

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update

                %% This function PERFORMS the MERGE sort algorithm.
            elseif app.SORTINGTYPESKnob.Value == num2str(2)

                unsortedvariables = mergeSorti(unsortedvariables,1,length(unsortedvariables),app,event);

                bar(app.UIAxesVisualisation,unsortedvariables)
                text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                    'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
                drawnow update
            end
            message1 = sprintf('THE SORTING HAS BEEN COMPLETED!');
            msgbox(message1, 'modal');
        end

        % Button pushed function: SKIPButton
        function SKIPButtonPushed(app, event)
            global unsortedvariables
            unsortedvariables = insertionnsortfunction(unsortedvariables);
            bar(app.UIAxesVisualisation,unsortedvariables)
            text(app.UIAxesVisualisation,1:length(unsortedvariables),unsortedvariables,num2str(unsortedvariables'),...
                'HorizontalAlignment','center','VerticalAlignment','baseline','Color','r','FontWeight','bold')
            drawnow update
            message1 = sprintf('THE SORTING HAS BEEN COMPLETED!');
            msgbox(message1, 'modal');
            clear unsortedvariables
            clear STARTButton
            while true
                pause(1)
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxesVisualisation);
            app.LENGTHOFARRAYKnob.Value=0;
            app.MAXIMUMVALUEOFARRAYKnob.Value=0;
            app.SPEEDSlider.Value=50;
        end

        % Callback function
        function HELPButton_6Pushed(app, event)
            web('https://www.geeksforgeeks.org/sorting-algorithms/')
        end

        % Callback function
        function PAUSEButtonPushed(app, event)

        end

        % Value changed function: PAUSEButton_2
        function PAUSEButton_2ValueChanged(app, event)

            global pausevalue projectspeed
            pausevalue = app.PAUSEButton_2.Value;

            projectspeed = 0.01*(100-app.SPEEDSlider.Value);
            while pausevalue==true
                if app.PAUSEButton_2.Value == 0
                    break;
                end
                pause(1)
            end
        end

        % Image clicked function: IncreaseMaxValueButton
        function IncreaseMaxValueButtonImageClicked(app, event)
            app.MAXIMUMVALUEOFARRAYKnob.Limits=app.MAXIMUMVALUEOFARRAYKnob.Limits*10;
        end

        % Image clicked function: IncreaseLengthButton
        function IncreaseLengthButtonImageClicked2(app, event)
            app.LENGTHOFARRAYKnob.Limits=app.LENGTHOFARRAYKnob.Limits*10;
        end

        % Button pushed function: CompareAllButton
        function CompareAllButtonPushed(app, event)
            % Which sorting methods are being compared.
            sorting_methods = [1 1 1 1 1 1 1 1];
            bar(app.UIAxesTimeComparison,[],[]);
            range_of_array = app.RangeEditField.Value;
            number_of_runs = app.ArraySizeEditField.Value;
            iteration_number = app.ArrayNumberEditField.Value;
            % Calling the Time_Comparison method.
            Time_Comparison(app,sorting_methods,range_of_array,number_of_runs,iteration_number)
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            app.HeapSortCheckBox.Value=0;
            app.BubbleSortCheckBox.Value=0;
            app.InsertionSortCheckBox.Value=0;
            app.MergeSortCheckBox.Value=0;
            app.QuickSortCheckBox.Value=0;
            app.BucketSortCheckBox.Value=0;
            app.RadixSortCheckBox.Value=0;
            app.CountingSortCheckBox.Value=0;
            cla(app.UIAxesTimeComparison);
        end

        % Button pushed function: CompareSelectedButton
        function CompareSelectedButtonPushed(app, event)
            if app.HeapSortCheckBox.Value==0 && app.BubbleSortCheckBox.Value==0 && ...
                    app.InsertionSortCheckBox.Value==0 && app.MergeSortCheckBox.Value==0 && ...
                    app.QuickSortCheckBox.Value==0 && app.BucketSortCheckBox.Value==0 && ...
                    app.RadixSortCheckBox.Value==0 && app.CountingSortCheckBox.Value==0
                msgbox('Please check the algorithms you want to compare...', 'Error','error');
            else
                checked_methods=[0 0 0 0 0 0 0 0];
                bar(app.UIAxesTimeComparison,[],[]);
                range_of_array = app.RangeEditField.Value;
                number_of_runs = app.ArraySizeEditField.Value;
                iteration_number = app.ArrayNumberEditField.Value;

                if app.InsertionSortCheckBox.Value
                    checked_methods(1) = 1;
                else
                    checked_methods(1) = 0;
                end
                if app.BubbleSortCheckBox.Value
                    checked_methods(2) = 1;
                else
                    checked_methods(2) = 0;
                end
                if app.MergeSortCheckBox.Value
                    checked_methods(3) = 1;
                else
                    checked_methods(3) = 0;
                end
                if app.BucketSortCheckBox.Value
                    checked_methods(4) = 1;
                else
                    checked_methods(4) = 0;
                end
                if app.RadixSortCheckBox.Value
                    checked_methods(5) = 1;
                else
                    checked_methods(5) = 0;
                end
                if app.CountingSortCheckBox.Value
                    checked_methods(6) = 1;
                else
                    checked_methods(6) = 0;
                end
                if app.QuickSortCheckBox.Value
                    checked_methods(7) = 1;
                else
                    checked_methods(7) = 0;
                end
                if app.HeapSortCheckBox.Value
                    checked_methods(8) = 1;
                else
                    checked_methods(8) = 0;
                end
                Time_Comparison(app,checked_methods,range_of_array,number_of_runs,iteration_number)
            end
        end

        % Button pushed function: SetRandomArrayButton
        function SetRandomArrayButtonPushed(app, event)
            app.sorted_array.Value='';
            cla(app.UIAxesSortedArray)
            app.take_number.Value='';
            app.displayindex.Value='';
            app.ArraySizeEditField_2.Value=randi([0,50],1,1);
            app.LowerBoundEditField.Value=randi([-25,0],1,1);
            app.UpperBoundEditField.Value= randi([75,150],1,1);
            app.unsortedarray=[];
            while length(app.unsortedarray)<app.ArraySizeEditField_2.Value
                x=randi([app.LowerBoundEditField.Value,app.UpperBoundEditField.Value],1,1);
                if ~(ismember(x,app.unsortedarray))
                    app.unsortedarray = [app.unsortedarray x];
                end
            end

            bar(app.UIAxesUnsortedArray,app.unsortedarray,'FaceColor',[0.4 0 0.2],'EdgeColor',[0 .9 .9]);
            text(app.UIAxesUnsortedArray,1:length(app.unsortedarray),app.unsortedarray,num2str(app.unsortedarray'),'vert','bottom','horiz','center');
            app.unsorted_array_binary_search.Value=num2str(app.unsortedarray);
        end

        % Button pushed function: GenerateRandomArrayButton
        function GenerateRandomArrayButtonPushed(app, event)
            app.unsortedarray = [];
            app.sorted_array.Value = '';
            cla(app.UIAxesSortedArray)
            app.take_number.Value = '';
            app.displayindex.Value = '';
            try
                %disable_button(app)
                if (app.LowerBoundEditField.Value == 0 && app.UpperBoundEditField.Value == 0) || app.ArraySizeEditField.Value == 0
                    msgbox('Required blanks cannot be empty...', 'Error','error');
                else
                    if app.LowerBoundEditField.Value>=app.UpperBoundEditField.Value
                        msgbox('Lower range cannot be greater than upper range...', 'Error','error');
                    else
                        app.unsortedarray = [];
                        while length(app.unsortedarray)<app.ArraySizeEditField.Value
                            x=randi([app.LowerBoundEditField.Value,app.UpperBoundEditField.Value],1,1);
                            if ~(ismember(x,app.unsortedarray))
                                app.unsortedarray = [app.unsortedarray x];
                            end
                        end
                    end
                    bar(app.UIAxesUnsortedArray,app.unsortedarray,'FaceColor',[0.4 0 0.2],'EdgeColor',[0 .9 .9]);
                    text(app.UIAxesUnsortedArray,1:length(app.unsortedarray),app.unsortedarray,num2str(app.unsortedarray'),...
                        'vert','bottom','horiz','center');
                    app.unsorted_array_binary_search.Value = num2str(app.unsortedarray);
                end
                % enable_button(app).
            catch
                msgbox('Make sure to set to Array Size and Range...','Error');
                % enable_button(app).
            end
        end

        % Button pushed function: SortRandomArrayButton
        function SortRandomArrayButtonPushed(app, event)
            if isempty(app.unsortedarray)
                msgbox('Please enter an array...','Error')
            else
                sortedarray=insertion_sort(app,app.unsortedarray);
                app.sorted_array.Value=num2str(sortedarray);
                bar(app.UIAxesSortedArray,sortedarray,'FaceColor',[0.4 0 0.2],'EdgeColor',[0 .9 .9]);
                text(app.UIAxesSortedArray,1:length(sortedarray),sortedarray,num2str(sortedarray'),'vert','bottom','horiz','center');
            end
        end

        % Button pushed function: FindIndexButton
        function FindIndexButtonPushed(app, event)
            x_axis = (1:length(app.unsortedarray));
            wanted_value = str2double(app.take_number.Value);
            sortedarray = insertion_sort(app,app.unsortedarray);
            first_index = 1;
            last_index = length(sortedarray);
            if ismember(wanted_value,sortedarray)
                while (first_index <= last_index)
                    mid = ceil((last_index+first_index)/2);
                    mid_val = sortedarray(mid);
                    if mid_val == wanted_value
                        index = mid;
                        flag = 1;
                        break
                    elseif (wanted_value < mid_val)
                        last_index = mid-1;
                    else
                        first_index=mid+1;
                    end
                end
                if flag == 0
                    index = -1;
                end

                app.displayindex.Value = num2str(index);
                bar(app.UIAxesSortedArray,sortedarray,'FaceColor',[0.4 0 0.2],'EdgeColor',[0 .9 .9]);
                hold(app.UIAxesSortedArray,"on");
                bar(app.UIAxesSortedArray,x_axis(index),sortedarray(index),"r");
                text(app.UIAxesSortedArray,1:length(sortedarray),sortedarray,num2str(sortedarray'),'vert','bottom','horiz','center');
            else
                % f = errordlg('Please enter a valid array element...','Error');
                bar(app.UIAxesSortedArray,sortedarray,'FaceColor',[0.4 0 0.2],'EdgeColor',[0 .9 .9]);
                app.take_number.Value='';
                app.displayindex.Value='';
            end
        end

        % Button pushed function: ClearFieldButton
        function ClearFieldButtonPushed(app, event)
            clear(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITableFirstMatrix.Data(:) = [];
            app.UITableSecondMatrix.Data(:) = [];
            app.UITableFinalMatrix.Data(:) = [];
            app.row1.Value = 1;
            app.col1.Value = 1;
            app.col2.Value = 1;
        end

        % Button pushed function: CreateRandomMatrixButton
        function CreateRandomMatrixButtonPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            row = app.row1.Value;
            col = app.col1.Value;
            col2 = app.col2.Value;
            row2 = app.row2.Value;
            matrix2 = round(-100+1000*rand((row2),(col2))); % Create random matrix1.
            matrix1 = round(-100+1000*rand((row),(col)));   % Create random matrix2.

            app.UITableFirstMatrix.Data = matrix1;
            app.UITableSecondMatrix.Data = matrix2;
            get(app.UITableFirstMatrix,"Data");
            get(app.UITableSecondMatrix,"Data");
            app.UITableFinalMatrix.Data(:) = [];
        end

        % Button pushed function: CreateManualMatrixButton
        function CreateManualMatrixButtonPushed(app, event)
            global row
            global col
            global col2
            global matrix1
            global matrix2
            try
                row = app.row1.Value;
                col = app.col1.Value;
                col2 = app.col2.Value;
                matrix1 = zeros(row,col);
                matrix2 = zeros(col,col2);
                app.UITableFirstMatrix.Data = matrix1;
                app.UITableSecondMatrix.Data = matrix2;
                get(app.UITableFirstMatrix,"Data");
                get(app.UITableSecondMatrix,"Data");
                app.UITableFinalMatrix.Data(:) = [];
            catch
                msgbox('Please check the matrices.','Error','error')
            end
        end

        % Button pushed function: ButtonMultiply
        function ButtonMultiplyPushed(app, event)
            % The global variable is taken to be used.
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            % The data on the table is assigned to the matrix1 and matrix2
            % variables.
            matrix1 = app.UITableFirstMatrix.Data;
            matrix2 = app.UITableSecondMatrix.Data;
            % Show an error message if the data is blank.
            if isempty(matrix1) && isempty(matrix2)
                msgbox('Please check your matrix','Error','error')
            else
                if col ~= row2
                    msgbox('For matrix multiplication, the row of matrix one must be equal to the column of the matrix two','Error','error')
                else
                    [row , col] = size(matrix1);    % The size of 'matrix1' is determined.
                    [row2, col2] = size(matrix2);   % The size of 'matrix2' is determined.
                    matrix3 = zeros(row,col2);

                    % Find the result matrix. Multiplicate matrix1 and
                    % matrix2 and assigned to matrix3.
                    for i = 1:row
                        for j = 1:col2
                            for k = 1:col
                                matrix3(i,j) = matrix3(i,j) + (matrix1(i,k)* matrix2(k,j));
                            end
                        end
                    end
                    app.UITableFinalMatrix.Data = matrix3;
                end
            end
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITableFirstMatrix.Data;
            matrix2 = app.UITableSecondMatrix.Data;
            if isempty(matrix1) && isempty(matrix2)
                msgbox('Please check your matrix','Error','error')
                app.UITableFinalMatrix.Data(:) = [];
            else
                if col ~= col2 || row ~= row2
                    msgbox('Please check your matrix..','Error','error')
                    app.UITableFinalMatrix.Data(:) = [];
                else
                    [row , col] = size(matrix1);    % The size of 'matrix1' is determined.
                    [row2, col2] = size(matrix2);   % The size of 'matrix2' is determined.
                    % matrix3 = zeros(row,col2);
                    % Find the result matrix.
                    matrix3 = matrix1 + matrix2;
                end
                app.UITableFinalMatrix.Data = matrix3;
            end
        end

        % Button pushed function: ButtonMinus
        function ButtonMinusPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITableFirstMatrix.Data;
            matrix2 = app.UITableSecondMatrix.Data;
            if isempty(matrix1) && isempty(matrix2)
                msgbox('Please check your matrix','Error','error')
                app.UITableFinalMatrix.Data(:) = [];
            else
                if col ~= col2 || row ~= row2
                    msgbox('Please check your matrix..','Error','error')
                    app.UITableFinalMatrix.Data(:) = [];
                else
                    [row , col] = size(matrix1);    % The size of 'matrix1' is determined.
                    [row2, col2] = size(matrix2);   % The size of 'matrix2' is determined.
                    matrix3 = zeros(row,col2);
                    % Find the result matrix.
                    matrix3 = matrix1 - matrix2;
                end
                app.UITableFinalMatrix.Data = matrix3;
            end
        end

        % Image clicked function: ImageFirstMatrixExcel
        function ImageFirstMatrixExcelClicked(app, event)
            global matrix1
            matrix1 = [];
            %cla(app.UITableFirstMatrix)
            [FileNameBodeWrite, PathNameBoderWrite] = uigetfile({'*.xlsx'; '*.csv'}, 'Please select file..');
            file_name = [PathNameBoderWrite,FileNameBodeWrite];
            app.UITableFirstMatrix.Data = xlsread(file_name);
        end

        % Image clicked function: ImageSecondMatrixExcel
        function ImageSecondMatrixExcelClicked(app, event)
            global matrix2
            matrix2 = [];
            %cla(app.UITableSecondMatrix)
            [FileNameBodeWrite, PathNameBoderWrite] = uigetfile({'*.xlsx'; '*.csv'}, 'Please select file..');
            file_name = [PathNameBoderWrite,FileNameBodeWrite];
            app.UITableSecondMatrix.Data = xlsread(file_name);
        end

        % Image clicked function: Image12_3
        function Image12_3Clicked(app, event)
            global matrix3
            if isempty(matrix3)
                msgbox('Please check the matrix.','Error','error')
            else
                [FileNameBodeWrite, PathNameBoderWrite] = uiputfile({'*.xlsx'; '*.csv'}, 'Save File..',['defname' '.xlsx']);
                if FileNameBodeWrite ~= 0
                    if exist([PathNameBoderWrite FileNameBodeWrite],'file')
                        delete([PathNameBoderWrite FileNameBodeWrite]);
                    end
                    xlswrite([PathNameBoderWrite FileNameBodeWrite],app.UITableFinalMatrix.Data,1,'A2');
                end
            end
        end

        % Button pushed function: FindithFibonacciNumberButton
        function FindithFibonacciNumberButtonPushed(app, event)
            try
                number_input = app.EditField.Value;

                if number_input == 0
                    f = errordlg('Please enter a positive number...','Error');
                else
                    indexis = bar(app.UIAxesFibonacci,1:number_input,'FaceColor',[.8,.8,.8],'EdgeColor','r','LineWidth',1.5);
                    fibonacci_array = Fibonacci(app,number_input,indexis);
                    app.EditField2.Value = num2str(fibonacci_array(number_input));
                end
            catch
                msgbox('Please enter a valid number...')
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.EditField.Value = 0;
            app.EditField2.Value = '';
            cla(app.UIAxesFibonacci);
            app.TextArea.Value='';
        end

        % Button pushed function: ShowithSmallestButton
        function ShowithSmallestButtonPushed(app, event)
            try
                unsorted_arrays = sort(app.unsorted_array);
                x_axis = 1:length(unsorted_arrays);
                index = app.EditFieldIndex.Value;
                results = qselect(app,app.unsorted_array,index);
                app.TextAreaNumber.Value = num2str(results);

                bar(app.UIAxesSortedRandomized,x_axis,unsorted_arrays,'yellow');
                hold(app.UIAxesSortedRandomized,"on")
                bar(app.UIAxesSortedRandomized, x_axis(index),unsorted_arrays(index),'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                hold(app.UIAxesSortedRandomized,"off")
                for i=1:numel(unsorted_arrays)
                    title(app.UIAxesSortedRandomized,'Sorted Array');
                    text(app.UIAxesSortedRandomized,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end

            catch
                msgbox('Check your index or array....', 'Error','error');
                bar(app.UIAxesSortedRandomized,x_axis,unsorted_arrays,"black");
                for i=1:numel(unsorted_arrays)
                    title(app.UIAxesSortedRandomized,'Sorted Array');
                    text(app.UIAxesSortedRandomized,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end
            end
        end

        % Button pushed function: ClearButtonRandomized
        function ClearButtonRandomizedPushed(app, event)
            %%clear button%%
            app.TextArea_unsorted_randomized.Value = '';
            app.TextAreaNumber.Value = '';
            app.TextArea_sorted_randomized.Value = '';
            app.array_size_randomized.Value = 0;
            app.upper_bound_randomized.Value = 0;
            app.lower_bound_randomized.Value = 0;
            app.EditFieldIndex.Value = 1;
            cla(app.UIAxesUnsortedRandomized)
            cla(app.UIAxesSortedRandomized)
            app.unsorted_array = [];

        end

        % Button pushed function: RandomArrayButtonRandomized
        function RandomArrayButtonRandomizedPushed(app, event)
            try
                % Checkbox value.
                same = app.DontcontainthesamenumbersCheckBox.Value;
                value1 = app.array_size_randomized.Value;
                value2 = app.upper_bound_randomized.Value;
                value3 = app.lower_bound_randomized.Value;
                % Error for not input.
                if (value2 == 0 && value3 == 0) || value1 == 0
                    msgbox('Please check your array.','Error','error')
                else
                    if value3 >= value2
                        msgbox('Should be upper range greater than lower range..','Error','error')
                    else
                        if same
                            if (abs(value2-value3)<value1)
                                msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                            else
                                app.unsorted_array=[];
                                while 1
                                    app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)]; % Different random array.
                                    app.unsorted_array = unique(app.unsorted_array);                    % Unique function.

                                    if length(app.unsorted_array) == value1
                                        break
                                    end
                                end
                                % Different random array and not same number.
                                app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                app.TextArea_unsorted_randomized.Value = num2str(app.unsorted_array);
                                app.TextArea_sorted_randomized.Value = num2str(sort(app.unsorted_array));
                            end
                        else
                            app.unsorted_array=randi([value3 value2],1,value1);                         % Different random array.
                            app.TextArea_unsorted_randomized.Value = num2str(app.unsorted_array);
                            app.TextArea_sorted_randomized.Value = num2str(sort(app.unsorted_array));
                        end
                        bar(app.UIAxesUnsortedRandomized,app.unsorted_array,'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                        for i=1:numel(app.unsorted_array)
                            title(app.UIAxesUnsortedRandomized,'Unsorted Array');
                            text(app.UIAxesUnsortedRandomized,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                        end
                    end
                end
            catch
                msgbox('Please, check it array', 'Error','error');
            end
        end

        % Button pushed function: DefaultArrayButtonRandomized
        function DefaultArrayButtonRandomizedPushed(app, event)
            try
                same = app.DontcontainthesamenumbersCheckBox.Value;
                app.upper_bound_randomized.Value = randi([70,150],1,1);
                app.lower_bound_randomized.Value = randi([0,25],1,1);
                app.array_size_randomized.Value = randi([1,45],1,1);

                if same
                    app.unsorted_array = randperm(app.upper_bound_randomized.Value,app.array_size_randomized.Value); %%different random array and not same number
                    app.TextArea_unsorted_randomized.Value = num2str(app.unsorted_array);
                    app.TextArea_sorted_randomized.Value = num2str(sort(app.unsorted_array));

                else
                    % Different random array.
                    app.unsorted_array=randi([app.lower_bound_randomized.Value app.upper_bound_randomized.Value],1,app.array_size_randomized.Value);
                    app.TextArea_unsorted_randomized.Value = num2str(app.unsorted_array);
                    app.TextArea_sorted_randomized.Value = num2str(sort(app.unsorted_array));

                end
                bar(app.UIAxesUnsortedRandomized,app.unsorted_array,'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                for i = 1:numel(app.unsorted_array)
                    title(app.UIAxesUnsortedRandomized,'Unsorted Array');
                    text(app.UIAxesUnsortedRandomized,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                end
            catch
                msgbox('Please, check it array', 'Error','error');

            end
        end

        % Image clicked function: ImageSortingExcel
        function ImageSortingExcelClicked(app, event)
            global unsortedvariables
            [file_name,PathName] = uigetfile({'*.xls;*.xlsx;'},'Please select excel file for sorting array.');
            FileName = [PathName file_name];
            unsortedvariables = xlsread(FileName,1,'A:A');

            bar(app.UIAxesVisualisation,unsortedvariables,...
                'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            for i = 1:numel(unsortedvariables)
                text(app.UIAxesVisualisation,(i),unsortedvariables(i),num2str(unsortedvariables(i),'%0.0f'),...
                    'HorizontalAlignment','center','VerticalAlignment','bottom')
            end
        end

        % Image clicked function: ImageSaveDataSorting
        function ImageSaveDataSortingClicked(app, event)
            answer = questdlg('Which file would you like to save the Array?','SAVE','Txt File','Xlsx File','Csv File','Csv File');
            switch answer
                case 'Txt File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('.txt');
                        filename = fullfile(path,file);
                        if filename~=0                          % user pressed cancel
                            writematrix(app.unsorted_array,filename);
                        end
                    end
                case 'Xlsx File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('.xlsx');
                        filename = fullfile(path,file);
                        if filename~=0                          % user pressed cancel
                            writematrix(app.unsorted_array,filename);
                        end
                    end
                case 'Csv File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('*.csv');
                        filename = fullfile(path,file);
                        if filename~=0                              % user pressed cancel
                            writematrix(app.unsorted_array,filename);
                        end
                    end
            end
        end

        % Image clicked function: FeedBackImage
        function FeedBackImageClicked(app, event)
            web mailto:orhan.ozan351@gmail.com?subject='About the introductory algorithms project.'
        end

        % Image clicked function: UniversityLogo
        function UniversityLogoImageClicked(app, event)
            web('https://www.ikcu.edu.tr/', '-browser')
        end

        % Image clicked function: ImageHelpSortingVisualisation
        function ImageHelpSortingVisualisationClicked(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure = uifigure('Visible', 'off');
            app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure.Name = 'Introduction to Algorithms Project -- Created by Orhan Ozan Yıldız.';
            app.TabGroup = uitabgroup(app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure);
            app.DASHBOARDTab = uitab(app.TabGroup);
            app.DASHBOARDTab.Title = 'DASHBOARD';
            app.Image5_2 = uiimage(app.DASHBOARDTab);
            app.Image5_2.ImageSource = 'sorting_background.png';
            app.FeedBackImage = uiimage(app.DASHBOARDTab);
            app.FeedBackImage.ImageClickedFcn = createCallbackFcn(app, @FeedBackImageClicked, true);
            app.FeedBackImage.ImageSource = 'icon_feed_back.png';
            app.UniversityLogo = uiimage(app.DASHBOARDTab);
            app.UniversityLogo.ImageClickedFcn = createCallbackFcn(app, @UniversityLogoImageClicked, true);
            app.UniversityLogo.ImageSource = 'university_logo.png';
            app.Image4 = uiimage(app.DASHBOARDTab);
            app.Image4.ImageSource = 'main_screen.png';
            app.SORTINGTab = uitab(app.TabGroup);
            app.SORTINGTab.Title = 'SORTING';
            app.UIAxesVisualisation = uiaxes(app.SORTINGTab);
            title(app.UIAxesVisualisation, 'Sorting Algorithms Visualisation')
            app.UIAxesVisualisation.XAxisLocation = 'top';
            app.UIAxesVisualisation.XTick = [];
            app.UIAxesVisualisation.YTick = [];
            app.LENGTHOFARRAYKnobLabel = uilabel(app.SORTINGTab);
            app.LENGTHOFARRAYKnobLabel.Text = 'LENGTH OF ARRAY';
            app.LENGTHOFARRAYKnob = uiknob(app.SORTINGTab, 'continuous');
            app.LENGTHOFARRAYKnob.ValueChangedFcn = createCallbackFcn(app, @LENGTHOFARRAYKnobValueChanged, true);
            app.SORTINGTYPESKnobLabel = uilabel(app.SORTINGTab);
            app.SORTINGTYPESKnobLabel.Text = 'SORTING TYPES';
            app.SORTINGTYPESKnob = uiknob(app.SORTINGTab, 'discrete');
            app.SORTINGTYPESKnob.Items = {'INSERTION', 'MERGE', 'BUBBLE', 'HEAP', 'QUICK', 'COUNTING', 'RADIX', 'BUCKET'};
            app.SORTINGTYPESKnob.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SORTINGTYPESKnob.Value = '1';
            app.MAXIMUMVALUEOFARRAYKnobLabel = uilabel(app.SORTINGTab);
            app.MAXIMUMVALUEOFARRAYKnobLabel.Text = 'MAXIMUM VALUE OF ARRAY';
            app.MAXIMUMVALUEOFARRAYKnob = uiknob(app.SORTINGTab, 'continuous');
            app.MAXIMUMVALUEOFARRAYKnob.Limits = [0 1000];
            app.MAXIMUMVALUEOFARRAYKnob.ValueChangedFcn = createCallbackFcn(app, @MAXIMUMVALUEOFARRAYKnobValueChanged, true);
            app.SPEEDSliderLabel = uilabel(app.SORTINGTab);
            app.SPEEDSliderLabel.Text = 'SPEED';
            app.SPEEDSlider = uislider(app.SORTINGTab);
            app.SPEEDSlider.Limits = [20 100];
            app.SPEEDSlider.MajorTicks = [20 40 60 80 100];
            app.SPEEDSlider.ValueChangedFcn = createCallbackFcn(app, @SPEEDSliderValueChanged, true);
            app.SPEEDSlider.Value = 20;
            app.CLEARButton = uibutton(app.SORTINGTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SKIPButton = uibutton(app.SORTINGTab, 'push');
            app.SKIPButton.ButtonPushedFcn = createCallbackFcn(app, @SKIPButtonPushed, true);
            app.SKIPButton.Text = 'SKIP';
            app.STARTButton = uibutton(app.SORTINGTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.DecreaseLengthButton = uiimage(app.SORTINGTab);
            app.DecreaseLengthButton.ImageClickedFcn = createCallbackFcn(app, @DecreaseLengthButtonImageClicked, true);
            app.DecreaseLengthButton.ImageSource = 'button_decrease.png';
            app.DecreaseMaxValueButton = uiimage(app.SORTINGTab);
            app.DecreaseMaxValueButton.ImageClickedFcn = createCallbackFcn(app, @DecreaseMaxValueButtonImageClicked, true);
            app.DecreaseMaxValueButton.ImageSource = 'button_decrease.png';
            app.IncreaseLengthButton = uiimage(app.SORTINGTab);
            app.IncreaseLengthButton.ImageClickedFcn = createCallbackFcn(app, @IncreaseLengthButtonImageClicked2, true);
            app.IncreaseLengthButton.ImageSource = 'button_increase.png';
            app.IncreaseMaxValueButton = uiimage(app.SORTINGTab);
            app.IncreaseMaxValueButton.ImageClickedFcn = createCallbackFcn(app, @IncreaseMaxValueButtonImageClicked, true);
            app.IncreaseMaxValueButton.ImageSource = 'button_increase.png';
            app.PAUSEButton_2 = uibutton(app.SORTINGTab, 'state');
            app.PAUSEButton_2.ValueChangedFcn = createCallbackFcn(app, @PAUSEButton_2ValueChanged, true);
            app.PAUSEButton_2.Text = 'PAUSE';
            app.ImageSortingExcel = uiimage(app.SORTINGTab);
            app.ImageSortingExcel.ImageClickedFcn = createCallbackFcn(app, @ImageSortingExcelClicked, true);
            app.ImageSortingExcel.ImageSource = 'excel_icon.png';
            app.ImageHelpSortingVisualisation = uiimage(app.SORTINGTab);
            app.ImageHelpSortingVisualisation.ImageClickedFcn = createCallbackFcn(app, @ImageHelpSortingVisualisationClicked, true);
            app.ImageHelpSortingVisualisation.Enable = 'off';
            app.ImageHelpSortingVisualisation.Visible = 'off';
            app.ImageHelpSortingVisualisation.Tooltip = {'Help for Binary Search...'; ''};
            app.ImageHelpSortingVisualisation.ImageSource = 'icon_information.png';
            app.ImageSaveDataSorting = uiimage(app.SORTINGTab);
            app.ImageSaveDataSorting.ImageClickedFcn = createCallbackFcn(app, @ImageSaveDataSortingClicked, true);
            app.ImageSaveDataSorting.ImageSource = 'save_all.png';
            app.TIMECOMPARISONTab = uitab(app.TabGroup);
            app.TIMECOMPARISONTab.Title = 'TIME COMPARISON';
            app.UIAxesTimeComparison = uiaxes(app.TIMECOMPARISONTab);
            title(app.UIAxesTimeComparison, {'Time Comparison'; ''})
            xlabel(app.UIAxesTimeComparison, 'Array Length')
            ylabel(app.UIAxesTimeComparison, 'Time in Second')
            zlabel(app.UIAxesTimeComparison, 'Z')
            app.CountingSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.CountingSortCheckBox.Text = 'Counting Sort';
            app.RadixSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.RadixSortCheckBox.Text = 'Radix Sort';
            app.BucketSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.BucketSortCheckBox.Text = 'Bucket Sort';
            app.QuickSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.QuickSortCheckBox.Text = 'Quick Sort';
            app.HeapSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.HeapSortCheckBox.Text = 'Heap Sort';
            app.MergeSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.MergeSortCheckBox.Text = 'Merge Sort';
            app.InsertionSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.InsertionSortCheckBox.Text = 'Insertion Sort';
            app.BubbleSortCheckBox = uicheckbox(app.TIMECOMPARISONTab);
            app.BubbleSortCheckBox.Text = 'Bubble Sort';
            app.CompareAllButton = uibutton(app.TIMECOMPARISONTab, 'push');
            app.CompareAllButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllButtonPushed, true);
            app.CompareAllButton.Text = 'Compare All';
            app.CompareSelectedButton = uibutton(app.TIMECOMPARISONTab, 'push');
            app.CompareSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSelectedButtonPushed, true);
            app.CompareSelectedButton.Text = 'Compare Selected ';
            app.ClearGraphButton = uibutton(app.TIMECOMPARISONTab, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.RangeEditField = uieditfield(app.TIMECOMPARISONTab, 'numeric');
            app.RangeEditField.Value = 1000;
            app.ArraySizeEditField = uieditfield(app.TIMECOMPARISONTab, 'numeric');
            app.ArraySizeEditField.Limits = [1 Inf];
            app.ArraySizeEditField.Value = 10;
            app.MaxRangeEditFieldLabel = uilabel(app.TIMECOMPARISONTab);
            app.MaxRangeEditFieldLabel.Text = 'Range';
            app.ArraySzieEditFieldLabel = uilabel(app.TIMECOMPARISONTab);
            app.ArraySzieEditFieldLabel.Text = 'Array Size';
            app.ArrayNumberEditFieldLabel = uilabel(app.TIMECOMPARISONTab);
            app.ArrayNumberEditFieldLabel.Text = 'Array Number';
            app.ArrayNumberEditField = uieditfield(app.TIMECOMPARISONTab, 'numeric');
            app.ArrayNumberEditField.Limits = [1 Inf];
            app.ArrayNumberEditField.Value = 50;
            app.Image_2 = uiimage(app.TIMECOMPARISONTab);
            app.Image_2.Enable = 'off';
            app.Image_2.Visible = 'off';
            app.Image_2.Tooltip = {'Help for Binary Search...'; ''};
            app.Image_2.ImageSource = 'icon_information.png';
            app.BINARYSEARCHTab = uitab(app.TabGroup);
            app.BINARYSEARCHTab.Title = 'BINARY SEARCH';
            app.UIAxesUnsortedArray = uiaxes(app.BINARYSEARCHTab);
            title(app.UIAxesUnsortedArray, {'Unsorted Array'; ''})
            xlabel(app.UIAxesUnsortedArray, 'X')
            ylabel(app.UIAxesUnsortedArray, 'Y')
            zlabel(app.UIAxesUnsortedArray, 'Z')
            app.UIAxesSortedArray = uiaxes(app.BINARYSEARCHTab);
            title(app.UIAxesSortedArray, {'Sorted Array'; ''})
            xlabel(app.UIAxesSortedArray, 'X')
            ylabel(app.UIAxesSortedArray, 'Y')
            zlabel(app.UIAxesSortedArray, 'Z')
            app.sorted_array = uitextarea(app.BINARYSEARCHTab);
            app.sorted_array.Editable = 'off';
            app.SortRandomArrayButton = uibutton(app.BINARYSEARCHTab, 'push');
            app.SortRandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SortRandomArrayButtonPushed, true);
            app.SortRandomArrayButton.Text = 'Sort Random Array';
            app.take_number = uieditfield(app.BINARYSEARCHTab, 'text');
            app.displayindex = uitextarea(app.BINARYSEARCHTab);
            app.displayindex.Editable = 'off';
            app.unsorted_array_binary_search = uitextarea(app.BINARYSEARCHTab);
            app.unsorted_array_binary_search.Editable = 'off';
            app.GenerateRandomArrayButton = uibutton(app.BINARYSEARCHTab, 'push');
            app.GenerateRandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomArrayButtonPushed, true);
            app.GenerateRandomArrayButton.Text = 'Generate Random Array';
            app.ArraySizeEditField_2 = uieditfield(app.BINARYSEARCHTab, 'numeric');
            app.LowerBoundEditField = uieditfield(app.BINARYSEARCHTab, 'numeric');
            app.ArraySizeLabel = uilabel(app.BINARYSEARCHTab);
            app.ArraySizeLabel.Text = 'Array Size';
            app.LowerBoundLabel = uilabel(app.BINARYSEARCHTab);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.BINARYSEARCHTab);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.BINARYSEARCHTab, 'numeric');
            app.SetRandomArrayButton = uibutton(app.BINARYSEARCHTab, 'push');
            app.SetRandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SetRandomArrayButtonPushed, true);
            app.SetRandomArrayButton.Text = 'Set Random Array';
            app.FindIndexButton = uibutton(app.BINARYSEARCHTab, 'push');
            app.FindIndexButton.ButtonPushedFcn = createCallbackFcn(app, @FindIndexButtonPushed, true);
            app.FindIndexButton.Text = 'Find Index';
            app.ClearFieldButton = uibutton(app.BINARYSEARCHTab, 'push');
            app.ClearFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFieldButtonPushed, true);
            app.ClearFieldButton.Text = 'Clear Field';
            app.Image_3 = uiimage(app.BINARYSEARCHTab);
            app.Image_3.Enable = 'off';
            app.Image_3.Visible = 'off';
            app.Image_3.Tooltip = {'Help for Binary Search...'; ''};
            app.Image_3.ImageSource = 'icon_information.png';
            app.BINARYSEARCHLabel = uilabel(app.BINARYSEARCHTab);
            app.BINARYSEARCHLabel.Text = 'BINARY SEARCH';
            app.MATRIXOPERATIONSTab = uitab(app.TabGroup);
            app.MATRIXOPERATIONSTab.Title = 'MATRIX OPERATIONS';
            app.Image5 = uiimage(app.MATRIXOPERATIONSTab);
            app.Image5.ImageSource = 'sorting_background.png';
            app.UITableFirstMatrix = uitable(app.MATRIXOPERATIONSTab);
            app.UITableFirstMatrix.ColumnName = {''};
            app.UITableFirstMatrix.RowName = {};
            app.UITableFirstMatrix.ColumnSortable = true;
            app.UITableFirstMatrix.ColumnEditable = true;
            app.UITableSecondMatrix = uitable(app.MATRIXOPERATIONSTab);
            app.UITableSecondMatrix.ColumnName = {''};
            app.UITableSecondMatrix.RowName = {};
            app.UITableSecondMatrix.ColumnSortable = true;
            app.UITableSecondMatrix.ColumnEditable = true;
            app.UITableFinalMatrix = uitable(app.MATRIXOPERATIONSTab);
            app.UITableFinalMatrix.ColumnName = {''};
            app.UITableFinalMatrix.RowName = {};
            app.ButtonMultiply = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.ButtonMultiply.ButtonPushedFcn = createCallbackFcn(app, @ButtonMultiplyPushed, true);
            app.ButtonMultiply.Icon = 'multiply.png';
            app.ButtonMultiply.Text = '';
            app.SecondMatrixLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.SecondMatrixLabel.Text = 'Second Matrix';
            app.ResultMatrixLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.ResultMatrixLabel.Text = 'Result Matrix';
            app.ImageFirstMatrixExcel = uiimage(app.MATRIXOPERATIONSTab);
            app.ImageFirstMatrixExcel.ImageClickedFcn = createCallbackFcn(app, @ImageFirstMatrixExcelClicked, true);
            app.ImageFirstMatrixExcel.ImageSource = 'excel_icon.png';
            app.ImageSecondMatrixExcel = uiimage(app.MATRIXOPERATIONSTab);
            app.ImageSecondMatrixExcel.ImageClickedFcn = createCallbackFcn(app, @ImageSecondMatrixExcelClicked, true);
            app.ImageSecondMatrixExcel.ImageSource = 'excel_icon.png';
            app.Image12_3 = uiimage(app.MATRIXOPERATIONSTab);
            app.Image12_3.ImageClickedFcn = createCallbackFcn(app, @Image12_3Clicked, true);
            app.Image12_3.ImageSource = 'save_all.png';
            app.FirstMatrixRowLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.FirstMatrixRowLabel.Text = 'First Matrix Row ';
            app.row1 = uieditfield(app.MATRIXOPERATIONSTab, 'numeric');
            app.row1.Limits = [1 Inf];
            app.row1.Value = 1;
            app.FirstMatrixColumnLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.FirstMatrixColumnLabel.Text = 'First Matrix Column';
            app.col1 = uieditfield(app.MATRIXOPERATIONSTab, 'numeric');
            app.col1.Limits = [1 Inf];
            app.col1.Value = 1;
            app.ImageInformationMatrixMux = uiimage(app.MATRIXOPERATIONSTab);
            app.ImageInformationMatrixMux.Enable = 'off';
            app.ImageInformationMatrixMux.Visible = 'off';
            app.ImageInformationMatrixMux.Tooltip = {'Help for Binary Search...'; ''};
            app.ImageInformationMatrixMux.ImageSource = 'icon_information.png';
            app.ClearButton = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CreateRandomMatrixButton = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.CreateRandomMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRandomMatrixButtonPushed, true);
            app.CreateRandomMatrixButton.Text = 'Create Random Matrix';
            app.CreateManualMatrixButton = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.CreateManualMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateManualMatrixButtonPushed, true);
            app.CreateManualMatrixButton.Text = 'Create Manual Matrix';
            app.ButtonAdd = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Icon = 'add.png';
            app.ButtonAdd.Text = '';
            app.ButtonMinus = uibutton(app.MATRIXOPERATIONSTab, 'push');
            app.ButtonMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.ButtonMinus.Icon = 'minus.png';
            app.ButtonMinus.Text = '';
            app.SecondMatrixRowLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.SecondMatrixRowLabel.Text = 'Second Matrix Row';
            app.row2 = uieditfield(app.MATRIXOPERATIONSTab, 'numeric');
            app.row2.Limits = [1 Inf];
            app.row2.Value = 1;
            app.SecondMatrixColumnLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.SecondMatrixColumnLabel.Text = 'Second Matrix Column';
            app.col2 = uieditfield(app.MATRIXOPERATIONSTab, 'numeric');
            app.col2.Limits = [1 Inf];
            app.col2.Value = 1;
            app.FirstMatrixLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.FirstMatrixLabel.Text = 'First Matrix';
            app.MatrixOperationsLabel = uilabel(app.MATRIXOPERATIONSTab);
            app.MatrixOperationsLabel.Text = 'Matrix Operations';
            app.FIBONACCITab = uitab(app.TabGroup);
            app.FIBONACCITab.Title = 'FIBONACCI';
            app.UIAxesFibonacci = uiaxes(app.FIBONACCITab);
            title(app.UIAxesFibonacci, {'Fibonacci Numbers'; ''})
            xlabel(app.UIAxesFibonacci, 'X')
            ylabel(app.UIAxesFibonacci, 'Y')
            zlabel(app.UIAxesFibonacci, 'Z')
            app.FindithFibonacciNumberButton = uibutton(app.FIBONACCITab, 'push');
            app.FindithFibonacciNumberButton.ButtonPushedFcn = createCallbackFcn(app, @FindithFibonacciNumberButtonPushed, true);
            app.FindithFibonacciNumberButton.Text = 'Find i''th Fibonacci Number';
            app.EditField2 = uieditfield(app.FIBONACCITab, 'text');
            app.TextArea = uitextarea(app.FIBONACCITab);
            app.EditField = uieditfield(app.FIBONACCITab, 'numeric');
            app.FibonacciArrayLabel = uilabel(app.FIBONACCITab);
            app.FibonacciArrayLabel.Text = 'Fibonacci Array';
            app.ClearButton_2 = uibutton(app.FIBONACCITab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.FIBONACCISEQUENCELabel = uilabel(app.FIBONACCITab);
            app.FIBONACCISEQUENCELabel.Text = 'FIBONACCI SEQUENCE';
            app.ithnumberisLabel = uilabel(app.FIBONACCITab);
            app.ithnumberisLabel.Text = 'i''th number is:';
            app.ImageInformationMatrixMux_2 = uiimage(app.FIBONACCITab);
            app.ImageInformationMatrixMux_2.Enable = 'off';
            app.ImageInformationMatrixMux_2.Visible = 'off';
            app.ImageInformationMatrixMux_2.Tooltip = {'Help for Binary Search...'; ''};
            app.ImageInformationMatrixMux_2.ImageSource = 'icon_information.png';
            app.RANDOMIZEDSELECTIONTab = uitab(app.TabGroup);
            app.RANDOMIZEDSELECTIONTab.Title = 'RANDOMIZED SELECTION';
            app.UIAxesUnsortedRandomized = uiaxes(app.RANDOMIZEDSELECTIONTab);
            app.UIAxesSortedRandomized = uiaxes(app.RANDOMIZEDSELECTIONTab);
            app.RANDOMIZEDSELECTIONLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.RANDOMIZEDSELECTIONLabel.Text = 'RANDOMIZED SELECTION';
            app.UpperBoundSpinnerLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.UpperBoundSpinnerLabel.Text = 'Upper Bound';
            app.upper_bound_randomized = uispinner(app.RANDOMIZEDSELECTIONTab);
            app.LowerBoundSpinnerLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.LowerBoundSpinnerLabel.Text = 'Lower Bound';
            app.lower_bound_randomized = uispinner(app.RANDOMIZEDSELECTIONTab);
            app.RandomArrayButtonRandomized = uibutton(app.RANDOMIZEDSELECTIONTab, 'push');
            app.RandomArrayButtonRandomized.ButtonPushedFcn = createCallbackFcn(app, @RandomArrayButtonRandomizedPushed, true);
            app.RandomArrayButtonRandomized.Text = 'Random Array';
            app.DefaultArrayButtonRandomized = uibutton(app.RANDOMIZEDSELECTIONTab, 'push');
            app.DefaultArrayButtonRandomized.ButtonPushedFcn = createCallbackFcn(app, @DefaultArrayButtonRandomizedPushed, true);
            app.DefaultArrayButtonRandomized.Text = 'Default Array';
            app.DontcontainthesamenumbersCheckBox = uicheckbox(app.RANDOMIZEDSELECTIONTab);
            app.DontcontainthesamenumbersCheckBox.Text = 'Don''t contain the same numbers.';
            app.ShowithSmallestButton = uibutton(app.RANDOMIZEDSELECTIONTab, 'push');
            app.ShowithSmallestButton.ButtonPushedFcn = createCallbackFcn(app, @ShowithSmallestButtonPushed, true);
            app.ShowithSmallestButton.Text = 'Show i''th Smallest';
            app.TextAreaNumber = uitextarea(app.RANDOMIZEDSELECTIONTab);
            app.TextAreaNumber.Editable = 'off';
            app.TextAreaNumber.Value = {'1'};
            app.EditFieldIndex = uieditfield(app.RANDOMIZEDSELECTIONTab, 'numeric');
            app.EditFieldIndex.Value = 1;
            app.ClearButtonRandomized = uibutton(app.RANDOMIZEDSELECTIONTab, 'push');
            app.ClearButtonRandomized.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonRandomizedPushed, true);
            app.ClearButtonRandomized.Text = 'Clear';
            app.IndexLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.IndexLabel.Text = 'Index';
            app.ithsmallestnumberLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.ithsmallestnumberLabel.Text = 'i''th smallest number';
            app.TextArea_sorted_randomized = uitextarea(app.RANDOMIZEDSELECTIONTab);
            app.TextArea_sorted_randomized.Editable = 'off';
            app.SortedArrayLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.SortedArrayLabel.Text = 'Sorted Array';
            app.TextArea_unsorted_randomized = uitextarea(app.RANDOMIZEDSELECTIONTab);
            app.TextArea_unsorted_randomized.Editable = 'off';
            app.UnsortedArrayLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.UnsortedArrayLabel.Text = 'Unsorted Array';
            app.ImageInformationMatrixMux_3 = uiimage(app.RANDOMIZEDSELECTIONTab);
            app.ImageInformationMatrixMux_3.Enable = 'off';
            app.ImageInformationMatrixMux_3.Visible = 'off';
            app.ImageInformationMatrixMux_3.Tooltip = {'Help for Binary Search...'; ''};
            app.ImageInformationMatrixMux_3.ImageSource = 'icon_information.png';
            app.ArraySizeSpinnerLabel = uilabel(app.RANDOMIZEDSELECTIONTab);
            app.ArraySizeSpinnerLabel.Text = 'Array Size';
            app.array_size_randomized = uispinner(app.RANDOMIZEDSELECTIONTab);
            % Show the figure after all components are created
            app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui_algorithms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntroductiontoAlgorithmsProjectCreatedbyOrhanOzanYldzUIFigure)
        end
    end
end